classdef thegame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        Welcome                  matlab.ui.container.Tab
        StartButton              matlab.ui.control.Button
        RhythmPurgatoryLabel     matlab.ui.control.Label
        SongSelector             matlab.ui.container.Tab
        NoBeatmap                matlab.ui.control.Label
        DifficultyDropDown       matlab.ui.control.DropDown
        DifficultyDropDownLabel  matlab.ui.control.Label
        SongDropDown             matlab.ui.control.DropDown
        SongDropDownLabel        matlab.ui.control.Label
        ArtistDropDown           matlab.ui.control.DropDown
        ArtistDropDownLabel      matlab.ui.control.Label
        GameStartButton          matlab.ui.control.Button
        Game                     matlab.ui.container.Tab
        CountDown                matlab.ui.control.Label
        GamingButton             matlab.ui.control.Button
        Finish                   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        SongLibrary
        Songs
        Artists
        SongDuration
        RecordingStartTime
        RecordedBeats
        ActiveKeys
        Timer
        Player
        Mode
    end
     
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.TabGroup.TabLocation = "bottom";
        app.TabGroup.SelectedTab = app.Welcome;
        app.DifficultyDropDown.Visible = false;
        app.DifficultyDropDownLabel.Visible = false;
        app.NoBeatmap.Visible=false;
        app.CountDown.Visible = "off";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % run a function that loads the start files and initializes
            % dropdowns
            app.SongLibrary = loadSongLibrary(app);
            
                    
        app.Songs = unique(app.SongLibrary.Song);
        app.Artists = unique(app.SongLibrary.Artist); 
        app.ArtistDropDown.Items = ["All"; app.Artists];
        app.SongDropDown.Items = ["";app.Songs];

        app.ArtistDropDown.Value = "All";
        app.TabGroup.SelectedTab = app.SongSelector;

        % assign existing beatmap matrix to applicable songs

        end

        % Value changed function: ArtistDropDown
        function ArtistDropDownValueChanged(app, event)
            selectedArtist = app.ArtistDropDown.Value;

        if strcmp(selectedArtist, "All")
            songs = unique(app.SongLibrary.Song);
        else
            filter = strcmp(app.SongLibrary.Artist, selectedArtist);
            songs = unique(app.SongLibrary.Song(filter));
        end

        app.SongDropDown.Items = songs;

        if ~isempty(songs)
            app.SongDropDown.Value = songs(1);  % Select first item
            SongDropDownValueChanged(app, []);  % Manually call the logic
        end
        
        end

        % Value changed function: SongDropDown
        function SongDropDownValueChanged(app, event)
            selectedSong = app.SongDropDown.Value;
            
        % Filter songLibrary for that song
            songRows = strcmp(app.SongLibrary.Song, selectedSong);

        % Check if there's any non-missing difficulty for that song
            difficulties = app.SongLibrary.Difficulty(songRows);

        if isempty(difficulties) || all(strlength(string(difficulties)) == 0)
            % No difficulties = hide the dropdown
            app.DifficultyDropDown.Visible = false;
            app.DifficultyDropDownLabel.Visible = false;
            app.NoBeatmap.Visible = true;
            app.GameStartButton.Text = "Let's Go!!";
        else
            % There are difficulties! Show and populate
            app.DifficultyDropDown.Items = unique(difficulties);
            app.DifficultyDropDown.Visible = true;
            app.DifficultyDropDownLabel.Visible = true;
            app.NoBeatmap.Visible = false;  % hide the label if it exists
            app.GameStartButton.Text = "Game Start!";
        end            
        end

        % Button pushed function: GameStartButton
        function GameStartButtonPushed(app, event)
            if strcmp(app.GameStartButton.Text, "Let's Go!!")
                app.Mode = "Recording";
                app.GamingButton.Text = "Start Recording";
                app.TabGroup.SelectedTab = app.Game;
            else
            % start regular gameplay mode (later)
                app.Mode = "Gameplay";
                app.GamingButton.Text = "Let's Play!";
                app.TabGroup.SelectedTab = app.Game;
            end

        end

        % Button pushed function: GamingButton
        function GamingButtonPushed(app, event)
            selectedSong = app.SongDropDown.Value;
            songRow = strcmp(app.SongLibrary.Song,selectedSong);
            filepath = app.SongLibrary.TrackFile{find(songRow,1)};
            [audio, fs] = loadSongFile(filepath,app);
            app.RecordingStartTime = tic;


            app.GamingButton.Visible = "off";
            app.CountDown.Visible = "on";
            
            %Countdown
            for count = 3:-1:1
                app.CountDown.Text = sprintf('%d...',count);
                pause(1);
            end
            app.CountDown.Text = "Go!";


            % Setup and start the timer
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @(~,~) updateTimerDisplay(app));
            start(app.Timer);

            % play song
            app.Player = audioplayer(audio, fs);
            play(app.Player);

            app.CountDown.Visible = "off";
            app.GamingButton.Visible = "on";

            switch(app.Mode)
                case "Recording"
                    %call another function to detect, record, and write
                    %shit to a new song_beatmap_csv file and assign it to
                    %the song that was chosen
                case "Gameplay"
                    %call another function that loads the beatmap and
                    %checks to see if the buttons clicked are matched with
                    %the beatmap
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Welcome = uitab(app.TabGroup);
            app.Welcome.Title = 'Tab';
            app.RhythmPurgatoryLabel = uilabel(app.Welcome);
            app.RhythmPurgatoryLabel.Text = 'Rhythm Purgatory';
            app.StartButton = uibutton(app.Welcome, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SongSelector = uitab(app.TabGroup);
            app.SongSelector.Title = 'Tab2';
            app.GameStartButton = uibutton(app.SongSelector, 'push');
            app.GameStartButton.ButtonPushedFcn = createCallbackFcn(app, @GameStartButtonPushed, true);
            app.GameStartButton.Text = 'Game Start!';
            app.ArtistDropDownLabel = uilabel(app.SongSelector);
            app.ArtistDropDownLabel.Text = 'Artist';
            app.ArtistDropDown = uidropdown(app.SongSelector);
            app.ArtistDropDown.Items = {'All'};
            app.ArtistDropDown.ValueChangedFcn = createCallbackFcn(app, @ArtistDropDownValueChanged, true);
            app.ArtistDropDown.Value = 'All';
            app.SongDropDownLabel = uilabel(app.SongSelector);
            app.SongDropDownLabel.Text = 'Song';
            app.SongDropDown = uidropdown(app.SongSelector);
            app.SongDropDown.Items = {'Loading...'};
            app.SongDropDown.ValueChangedFcn = createCallbackFcn(app, @SongDropDownValueChanged, true);
            app.SongDropDown.Value = 'Loading...';
            app.DifficultyDropDownLabel = uilabel(app.SongSelector);
            app.DifficultyDropDownLabel.Text = 'Difficulty';
            app.DifficultyDropDown = uidropdown(app.SongSelector);
            app.DifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.DifficultyDropDown.Placeholder = '""';
            app.DifficultyDropDown.Value = 'Easy';
            app.NoBeatmap = uilabel(app.SongSelector);
            app.NoBeatmap.Text = 'There is no beatmap for this song yet. Wanna write your own?';
            app.Game = uitab(app.TabGroup);
            app.GamingButton = uibutton(app.Game, 'push');
            app.GamingButton.ButtonPushedFcn = createCallbackFcn(app, @GamingButtonPushed, true);
            app.GamingButton.Text = 'Start Recording';
            app.CountDown = uilabel(app.Game);
            app.CountDown.Text = '3...';
            app.Finish = uitab(app.TabGroup);
            app.Finish.Title = 'Tab3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thegame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end